classdef BladeDesignerBeta < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        BladeDesigner                  matlab.ui.Figure
        BladeDesignerBetaVersionLabel  matlab.ui.control.Label
        Selection                      matlab.ui.container.TabGroup
        Turbine                        matlab.ui.container.Tab
        CamberAxes                     matlab.ui.control.UIAxes
        ThicknessAxes                  matlab.ui.control.UIAxes
        BladeAxes                      matlab.ui.control.UIAxes
        InletCamberAngleSlider         matlab.ui.control.Slider
        OutletCamberAngleSliderLabel   matlab.ui.control.Label
        OutletCamberAngleSlider        matlab.ui.control.Slider
        InletCamberAngleSliderLabel    matlab.ui.control.Label
        StaggerAngleSlider             matlab.ui.control.Slider
        StaggerAngleSliderLabel        matlab.ui.control.Label
        ChoosebladethicknessprofileLabel  matlab.ui.control.Label
        Log                            matlab.ui.control.EditField
        LogdetailsLabel                matlab.ui.control.Label
        ThicknessOptions               matlab.ui.control.ListBox
        InletCamberAngleValue          matlab.ui.control.EditField
        OutletCamberAngleValue         matlab.ui.control.EditField
        StaggerAngleValue              matlab.ui.control.EditField
        AddthickprofileButton          matlab.ui.control.Button
        CamberLineAnglesLabel          matlab.ui.control.Label
        Label                          matlab.ui.control.Label
        Metal1Value                    matlab.ui.control.EditField
        Label_3                        matlab.ui.control.Label
        Metal2Value                    matlab.ui.control.EditField
        Label_2                        matlab.ui.control.Label
        CamberAngleValue               matlab.ui.control.EditField
        Label_4                        matlab.ui.control.Label
        Label_5                        matlab.ui.control.Label
        DeleteprofileButton            matlab.ui.control.Button
        CompressorTab                  matlab.ui.container.Tab
    end


    methods (Access = private)
        
        function plotting(app)
            % Save angles
            InletCamberAngle = app.InletCamberAngleSlider.Value;
            OutletCamberAngle = app.OutletCamberAngleSlider.Value;
            Stagger = app.StaggerAngleSlider.Value;
            
            % Obtain Camber Line
            Camber = CamberLine(InletCamberAngle,OutletCamberAngle,Stagger);

            % Obtain extra plots
            Extras = PlottingExtras(InletCamberAngle, OutletCamberAngle, Stagger, Camber(4,1000));
            Curve = Extras{2};
            Line1 = Extras{1};
            Line2 = Extras{3};
            Line3 = Extras{4};
            Curve2 = Extras{5};
            Curve3 = Extras{6};
            
            % Emergency messages - check them
            if InletCamberAngle - Stagger < 0
               message = 'Inlet Camber Angle is significantly small and/or Stagger is significantly large. Please, consider changing these values!';
               uialert(app.BladeDesigner,message,'Recommendation','Icon','info');
            end
            
            if OutletCamberAngle + Stagger >= 90
               message = 'Outlet Camber Angle and/or is significantly large. Please, consider changing these values!';
               uialert(app.BladeDesigner,message,'Recommendation','Icon','info');
            end
            
            % Obtain plots
                % Camber Line
            plot(app.CamberAxes,Camber(3,:),Camber(4,:))
            hold(app.CamberAxes,'on')
%              pan(app.CamberAxes,'on');
%             zoom(app.CamberAxes,'on');
                % Inlet Metal Arc
            plot(app.CamberAxes,Curve(1,:),Curve(2,:))
            hold(app.CamberAxes,'on')
                % Outlet Metal Arc
            plot(app.CamberAxes,Curve2(1,:),Curve2(2,:))
            hold(app.CamberAxes,'on')
                % Tangent line at inlet
            plot(app.CamberAxes,Line1(1,:),Line1(2,:))
            hold(app.CamberAxes,'on')
                % Horizontal line at outlet
            plot(app.CamberAxes,Line2(1,:),Line2(2,:))
            hold(app.CamberAxes,'on')
                % Tangent line at outlet
            plot(app.CamberAxes,Line3(1,:),Line3(2,:))
            hold(app.CamberAxes,'on')
                % Camber Angle Arc
            plot(app.CamberAxes,Curve3(1,:),Curve3(2,:))
            hold(app.CamberAxes,'off')
                % Print angles symbols
            t1 = text(app.CamberAxes,Curve(1,500)-0.03,Curve(2,500)-0.03,'\chi_{1}','HorizontalAlignment','left' );
            t2 = text(app.CamberAxes,Curve2(1,500)+0.03,Curve2(2,500)-0.03,'\chi_{2}','HorizontalAlignment','left' );
            t3 = text(app.CamberAxes,Curve3(1,1000)+0.03,Curve3(2,1000)-0.03,'\theta','HorizontalAlignment','left' );
            a1 = 14;
            a3 = 'bold';
            a4 = 'red';
            t1.FontSize = a1;
            t1.FontWeight = a3;
            t1.Color = a4;
            t2.FontSize = a1;
            t2.FontWeight = a3;
            t2.Color = a4;
            t3.FontSize = a1;
            t3.FontWeight = a3;
            t3.Color = a4;
        end
        
        
        function plotting2(app)
            % Load Angles
            InletCamberAngle = app.InletCamberAngleSlider.Value;
            OutletCamberAngle = app.OutletCamberAngleSlider.Value;
            Stagger = app.StaggerAngleSlider.Value;
            
            % Get Camber and Blade Profiles
            Camber = CamberLine(InletCamberAngle,OutletCamberAngle,Stagger);
            Blade = BladeProfile(Camber(1,:), Camber(2,:), app.ThicknessOptions.Value, Stagger);
            
            % Plot
            plot(app.BladeAxes,Blade{1,:},Blade{2,:})
            hold(app.BladeAxes,'on')
            plot(app.BladeAxes,Camber(3,:),Camber(4,:))
            hold(app.BladeAxes,'off')
%             pan(app.BladeAxes,'on');
%             zoom(app.BladeAxes,'on');
            plot(app.ThicknessAxes,Blade{3,:},Blade{4,:})
%             pan(app.ThicknessAxes,'on');
%             zoom(app.ThicknessAxes,'on');
        end
        
        function values_angles(app)
            InletCamberAngle = app.InletCamberAngleSlider.Value;
            OutletCamberAngle = app.OutletCamberAngleSlider.Value;
            Stagger = app.StaggerAngleSlider.Value;
            
            Metal1 = InletCamberAngle - Stagger;
            app.Metal1Value.Value = strcat(num2str(Metal1),char(176));
            
            Metal2 = OutletCamberAngle + Stagger;
            app.Metal2Value.Value = strcat(num2str(Metal2),char(176));
            
            CamberAngle = InletCamberAngle + OutletCamberAngle;
            app.CamberAngleValue.Value = strcat(num2str(CamberAngle),char(176));
        end
        
        function thickness(app)
            Options = importdata('ThicknessProfilesTurbine.dat');
            app.ThicknessOptions.Items = Options;
        end
        
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            thickness(app)
            app.ThicknessOptions.Value = app.ThicknessOptions.Items{1};
        end

        % Button pushed function: AddthickprofileButton
        function AddthickprofileButtonPushed(app, event)
            run('ThicknessProfile.mlapp');
        end

        % Value changed function: InletCamberAngleSlider
        function InletCamberAngleSliderValueChanged(app, event)
            InletCamberAngle = app.InletCamberAngleSlider.Value;
            app.InletCamberAngleValue.Value = strcat(num2str(InletCamberAngle), char(176));
            plotting(app)
            plotting2(app)
            values_angles(app)
            thickness(app)
        end

        % Value changed function: OutletCamberAngleSlider
        function OutletCamberAngleSliderValueChanged(app, event)
            OutletCamberAngle = app.OutletCamberAngleSlider.Value;
            app.OutletCamberAngleValue.Value = strcat(num2str(OutletCamberAngle), char(176));
            plotting(app)
            plotting2(app)
            values_angles(app)
            thickness(app)
        end

        % Value changed function: StaggerAngleSlider
        function StaggerAngleSliderValueChanged(app, event)
            Stagger = app.StaggerAngleSlider.Value;
            app.StaggerAngleValue.Value = strcat(num2str(Stagger), char(176));
            plotting(app)
            plotting2(app)
            values_angles(app)
            thickness(app)
        end

        % Value changed function: ThicknessOptions
        function ThicknessOptionsValueChanged(app, event)
            plotting(app)
            plotting2(app)
            thickness(app)
        end

        % Button pushed function: DeleteprofileButton
        function DeleteprofileButtonPushed(app, event)
            type = 'Turbine';
            ProfileName = app.ThicknessOptions.Value;
            Message = DeleteProfile(ProfileName,type);
            uialert(app.BladeDesigner,Message,'File uploaded','Icon','ok.png')
            thickness(app)
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.BladeDesigner = uifigure;
            app.BladeDesigner.Name = 'UI Figure';
            app.BladeDesignerBetaVersionLabel = uilabel(app.BladeDesigner);
            app.BladeDesignerBetaVersionLabel.Text = 'Blade Designer Beta Version';
            app.Selection = uitabgroup(app.BladeDesigner);
            app.Turbine = uitab(app.Selection);
            app.Turbine.Title = 'Turbine';
            app.CamberAxes = uiaxes(app.Turbine);
            title(app.CamberAxes, 'Camber Line')
            xlabel(app.CamberAxes, 'X')
            ylabel(app.CamberAxes, 'Y')
            app.CamberAxes.XAxisLocation = 'origin';
            app.CamberAxes.YAxisLocation = 'origin';
            app.ThicknessAxes = uiaxes(app.Turbine);
            title(app.ThicknessAxes, 'Thickness Profile')
            xlabel(app.ThicknessAxes, 'X')
            ylabel(app.ThicknessAxes, 'Y')
            app.ThicknessAxes.XAxisLocation = 'origin';
            app.ThicknessAxes.YAxisLocation = 'origin';
            app.BladeAxes = uiaxes(app.Turbine);
            title(app.BladeAxes, 'Blade Profile')
            xlabel(app.BladeAxes, 'X')
            ylabel(app.BladeAxes, 'Y')
            app.BladeAxes.XAxisLocation = 'origin';
            app.BladeAxes.YAxisLocation = 'origin';
            app.InletCamberAngleSlider = uislider(app.Turbine);
            app.InletCamberAngleSlider.Limits = [0 90];
            app.InletCamberAngleSlider.ValueChangedFcn = createCallbackFcn(app, @InletCamberAngleSliderValueChanged, true);
            app.OutletCamberAngleSliderLabel = uilabel(app.Turbine);
            app.OutletCamberAngleSliderLabel.Text = 'Outlet Camber Angle';
            app.OutletCamberAngleSlider = uislider(app.Turbine);
            app.OutletCamberAngleSlider.Limits = [0 90];
            app.OutletCamberAngleSlider.ValueChangedFcn = createCallbackFcn(app, @OutletCamberAngleSliderValueChanged, true);
            app.InletCamberAngleSliderLabel = uilabel(app.Turbine);
            app.InletCamberAngleSliderLabel.Text = 'Inlet Camber Angle';
            app.StaggerAngleSlider = uislider(app.Turbine);
            app.StaggerAngleSlider.Limits = [0 90];
            app.StaggerAngleSlider.ValueChangedFcn = createCallbackFcn(app, @StaggerAngleSliderValueChanged, true);
            app.StaggerAngleSliderLabel = uilabel(app.Turbine);
            app.StaggerAngleSliderLabel.Text = 'Stagger Angle';
            app.ChoosebladethicknessprofileLabel = uilabel(app.Turbine);
            app.ChoosebladethicknessprofileLabel.Text = 'Choose blade thickness profile';
            app.Log = uieditfield(app.Turbine, 'text');
            app.Log.Editable = 'off';
            app.LogdetailsLabel = uilabel(app.Turbine);
            app.LogdetailsLabel.Text = 'Log details';
            app.ThicknessOptions = uilistbox(app.Turbine);
            app.ThicknessOptions.Items = {};
            app.ThicknessOptions.ValueChangedFcn = createCallbackFcn(app, @ThicknessOptionsValueChanged, true);
            app.ThicknessOptions.Value = {};
            app.InletCamberAngleValue = uieditfield(app.Turbine, 'text');
            app.InletCamberAngleValue.Editable = 'off';
            app.OutletCamberAngleValue = uieditfield(app.Turbine, 'text');
            app.OutletCamberAngleValue.Editable = 'off';
            app.StaggerAngleValue = uieditfield(app.Turbine, 'text');
            app.StaggerAngleValue.Editable = 'off';
            app.AddthickprofileButton = uibutton(app.Turbine, 'push');
            app.AddthickprofileButton.ButtonPushedFcn = createCallbackFcn(app, @AddthickprofileButtonPushed, true);
            app.AddthickprofileButton.Text = 'Add thick profile';
            app.CamberLineAnglesLabel = uilabel(app.Turbine);
            app.CamberLineAnglesLabel.Text = 'Camber Line Angles';
            app.Label = uilabel(app.Turbine);
            app.Label.Text = 'χ';
            app.Metal1Value = uieditfield(app.Turbine, 'text');
            app.Metal1Value.Editable = 'off';
            app.Label_3 = uilabel(app.Turbine);
            app.Label_3.Text = 'χ';
            app.Metal2Value = uieditfield(app.Turbine, 'text');
            app.Metal2Value.Editable = 'off';
            app.Label_2 = uilabel(app.Turbine);
            app.Label_2.Text = 'θ';
            app.CamberAngleValue = uieditfield(app.Turbine, 'text');
            app.CamberAngleValue.Editable = 'off';
            app.Label_4 = uilabel(app.Turbine);
            app.Label_4.Text = '1';
            app.Label_5 = uilabel(app.Turbine);
            app.Label_5.Text = '2';
            app.DeleteprofileButton = uibutton(app.Turbine, 'push');
            app.DeleteprofileButton.ButtonPushedFcn = createCallbackFcn(app, @DeleteprofileButtonPushed, true);
            app.DeleteprofileButton.Text = 'Delete profile';
            app.CompressorTab = uitab(app.Selection);
            app.CompressorTab.Title = 'Compressor';
        end
    end

    methods (Access = public)

        % Construct app
        function app = BladeDesignerBeta

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.BladeDesigner)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.BladeDesigner)
        end
    end
end